classdef Image_Captioning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Image_Captioning_UIFigure  matlab.ui.Figure
        InputImageButton           matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        ImageAttentionButton       matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        path % 看圖說故事
        file
        textImage
        caption
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageAttentionButton
        function ImageAttentionButtonPushed(app, event)
            %att_Data = load('Image_attention_20200511.mat');
            %model = load('dlnet_20200511.mat');
            att_Data = load('Image_attention_20200505.mat');
            model = load('dlnet_20200505.mat');
            img = app.textImage;
            
            inputMin = 0;
            inputMax = 255;
            executionEnvironment = "auto";
            
            dlX = extractImageFeatures(model.dlnet,img,inputMin,inputMax,executionEnvironment);
            
            beamIndex = 3;
            maxNumWords = 20;
            [words,attentionScores] = beamSearch(dlX,beamIndex,att_Data.parametersEncoder,att_Data.parametersDecoder,att_Data.enc,maxNumWords);
            app.caption = join(words);
            app.Label.Text = app.caption;
            disp(app.caption);
            uialert(app.Image_Captioning_UIFigure,'執行結束(Finish)','Finish','Icon','success');
            
           %% Feature Extraction Function
            function dlX = extractImageFeatures(dlnet,X,inputMin,inputMax,executionEnvironment)
                
                % Resize and rescale.
                inputSize = dlnet.Layers(1).InputSize(1:2);
                X = imresize(X,inputSize);
                X = rescale(X,-1,1,'InputMin',inputMin,'InputMax',inputMax);
                
                % Convert to dlarray.
                dlX = dlarray(X,'SSCB');
                
                % Convert to gpuArray.
                if (executionEnvironment == "auto" && canUseGPU) || executionEnvironment == "gpu"
                    dlX = gpuArray(dlX);
                end
                
                % Extract features and reshape.
                dlX = predict(dlnet,dlX);
                sz = size(dlX);
                numFeatures = sz(1) * sz(2);
                inputSizeEncoder = sz(3);
                miniBatchSize = sz(4);
                dlX = reshape(dlX,[numFeatures inputSizeEncoder miniBatchSize]);
                
            end
           
            %% Beam Search Function
            function [words,attentionScores] = beamSearch(dlX,beamIndex,parametersEncoder,parametersDecoder, ...
                    enc,maxNumWords)
                
                % Model dimensions
                numFeatures = size(dlX,1);
                numHiddenUnits = size(parametersDecoder.attention.Weights1,1);
                
                % Extract features
                features = modelEncoder(dlX,parametersEncoder);
                
                % Initialize state
                state = struct;
                state.gru.HiddenState = dlarray(zeros([numHiddenUnits 1],'like',dlX));
                
                % Initialize candidates
                candidates = struct;
                candidates.State = state;
                candidates.Words = "<start>";
                candidates.Score = 0;
                candidates.AttentionScores = dlarray(zeros([numFeatures maxNumWords],'like',dlX));
                candidates.StopFlag = false;
                
                t = 0;
                
                % Loop over words
                while t < maxNumWords
                    t = t + 1;
                    
                    candidatesNew = [];
                    
                    % Loop over candidates
                    for i = 1:numel(candidates)
                        
                        % Stop generating when stop token is predicted
                        if candidates(i).StopFlag
                            continue
                        end
                        
                        % Candidate details
                        state = candidates(i).State;
                        words = candidates(i).Words;
                        score = candidates(i).Score;
                        attentionScores = candidates(i).AttentionScores;
                        
                        % Predict next token
                        decoderInput = word2ind(enc,words(end));
                        [dlYPred,state,attentionScores(:,t)] = modelDecoder(decoderInput,parametersDecoder,features,state);
                        
                        dlYPred = softmax(dlYPred,'DataFormat','CB');
                        [scoresTop,idxTop] = maxk(extractdata(dlYPred),beamIndex);
                        idxTop = gather(idxTop);
                        
                        % Loop over top predictions
                        for j = 1:beamIndex
                            candidate = struct;
                            
                            candidateWord = ind2word(enc,idxTop(j));
                            candidateScore = scoresTop(j);
                            
                            if candidateWord == "<stop>"
                                candidate.StopFlag = true;
                                attentionScores(:,t+1:end) = [];
                            else
                                candidate.StopFlag = false;
                            end
                            
                            candidate.State = state;
                            candidate.Words = [words candidateWord];
                            candidate.Score = score + log(candidateScore);
                            candidate.AttentionScores = attentionScores;
                            
                            candidatesNew = [candidatesNew candidate];
                        end
                    end
                    
                    % Get top candidates
                    [~,idx] = maxk([candidatesNew.Score],beamIndex);
                    candidates = candidatesNew(idx);
                    
                    % Stop predicting when all candidates have stop token
                    if all([candidates.StopFlag])
                        break
                    end
                end
                
                % Get top candidate
                words = candidates(1).Words(2:end-1);
                attentionScores = candidates(1).AttentionScores;
                
end

            function dlY = modelEncoder(dlX,parametersEncoder)
                
                [numFeatures,inputSizeEncoder,miniBatchSize] = size(dlX);
                
                % Fully connect
                weights = parametersEncoder.fc.Weights;
                bias = parametersEncoder.fc.Bias;
                embeddingDimension = size(weights,1);
                
                dlX = permute(dlX,[2 1 3]);
                dlX = reshape(dlX,inputSizeEncoder,numFeatures*miniBatchSize);
                dlY = fullyconnect(dlX,weights,bias,'DataFormat','CB');
                dlY = reshape(dlY,embeddingDimension,numFeatures,miniBatchSize);
                
                % ReLU
                dlY = relu(dlY);
                
            end
            
            function [dlY,state,attentionWeights] = modelDecoder(dlX,parametersDecoder,features,state)
                
                hiddenState = state.gru.HiddenState;
                
                % Attention
                weights1 = parametersDecoder.attention.Weights1;
                bias1 = parametersDecoder.attention.Bias1;
                weights2 = parametersDecoder.attention.Weights2;
                bias2 = parametersDecoder.attention.Bias2;
                weightsV = parametersDecoder.attention.WeightsV;
                biasV = parametersDecoder.attention.BiasV;
                [contextVector, attentionWeights] = attention(hiddenState,features,weights1,bias1,weights2,bias2,weightsV,biasV);
                
                % Embedding
                weights = parametersDecoder.emb.Weights;
                dlX = embedding(dlX,weights);
                
                % Concatenate
                dlY = cat(1,contextVector,dlX);
                
                % GRU
                inputWeights = parametersDecoder.gru.InputWeights;
                recurrentWeights = parametersDecoder.gru.RecurrentWeights;
                bias = parametersDecoder.gru.Bias;
                [dlY, hiddenState] = gru(dlY, hiddenState, inputWeights, recurrentWeights, bias, 'DataFormat','CBT');
                
                % Update state
                state.gru.HiddenState = hiddenState;
                
                % Fully connect
                weights = parametersDecoder.fc1.Weights;
                bias = parametersDecoder.fc1.Bias;
                dlY = fullyconnect(dlY,weights,bias,'DataFormat','CB');
                
                % Fully connect
                weights = parametersDecoder.fc2.Weights;
                bias = parametersDecoder.fc2.Bias;
                dlY = fullyconnect(dlY,weights,bias,'DataFormat','CB');
                
            end

            function [gradientsEncoder,gradientsDecoder,loss,dlYPred] = ...
                    modelGradients(parametersEncoder,parametersDecoder,dlX,dlT)
                
                miniBatchSize = size(dlX,3);
                sequenceLength = size(dlT,2) - 1;
                vocabSize = size(parametersDecoder.emb.Weights,2);
                
                % Model encoder
                features = modelEncoder(dlX,parametersEncoder);
                
                % Initialize state
                numHiddenUnits = size(parametersDecoder.attention.Weights1,1);
                state = struct;
                state.gru.HiddenState = dlarray(zeros([numHiddenUnits miniBatchSize],'single'));
                
                dlYPred = dlarray(zeros([vocabSize miniBatchSize sequenceLength],'like',dlX));
                loss = dlarray(single(0));
                
                padToken = vocabSize;
                
                for t = 1:sequenceLength
                    decoderInput = dlT(:,t);
                    
                    dlYReal = dlT(:,t+1);
                    
                    [dlYPred(:,:,t),state] = modelDecoder(decoderInput,parametersDecoder,features,state);
                    
                    mask = dlYReal ~= padToken;
                    
                    loss = loss + sparseCrossEntropyAndSoftmax(dlYPred(:,:,t),dlYReal,mask);
                end
                
                % Calculate gradients
                [gradientsEncoder,gradientsDecoder] = dlgradient(loss, parametersEncoder,parametersDecoder);
                
            end
            
            %% Sparse Cross Entropy and Softmax Loss Function
            function loss = sparseCrossEntropyAndSoftmax(dlY, dlT, mask)
                
                miniBatchSize = size(dlY, 2);
                
                % Softmax.
                dlY = softmax(dlY,'DataFormat','CB');
                
                % Find rows corresponding to the target words.
                idx = sub2ind(size(dlY), dlT', 1:miniBatchSize);
                dlY = dlY(idx);
                
                % Bound away from zero.
                dlY = max(dlY, single(1e-8));
                
                % Masked loss.
                loss = log(dlY) .* mask';
                loss = -sum(loss,'all') ./ miniBatchSize;
                
            end
                     
            %% Glorot Weight Initalization Function
            function weights = initializeGlorot(numOut, numIn)
                
                varWeights = sqrt( 6 / (numIn + numOut) );
                weights = varWeights * (2 * rand([numOut, numIn], 'single') - 1);
                
            end

            %% Embedding Function
            function Z = embedding(X, weights)
                
                % Reshape inputs into a vector
                [N, T] = size(X, 1:2);
                X = reshape(X, N*T, 1);
                
                % Index into embedding matrix
                Z = weights(:, X);
                
                % Reshape outputs by separating out batch and sequence dimensions
                Z = reshape(Z, [], N, T);
                
            end
            
            function [contextVector, attentionWeights] = attention(hidden,features,weights1, ...
                bias1,weights2,bias2,weightsV,biasV)
            
            % Model dimensions.
            [embeddingDimension,numFeatures,miniBatchSize] = size(features);
            numHiddenUnits = size(weights1,1);
            
            % Fully connect.
            dlY1 = reshape(features,embeddingDimension, numFeatures*miniBatchSize);
            dlY1 = fullyconnect(dlY1,weights1,bias1,'DataFormat','CB');
            dlY1 = reshape(dlY1,numHiddenUnits,numFeatures,miniBatchSize);
            
            % Fully connect.
            dlY2 = fullyconnect(hidden,weights2,bias2,'DataFormat','CB');
            dlY2 = reshape(dlY2,numHiddenUnits,1,miniBatchSize);
            
            % Addition, tanh.
            scores = tanh(dlY1 + dlY2);
            scores = reshape(scores, numHiddenUnits, numFeatures*miniBatchSize);
            
            % Fully connect, softmax.
            attentionWeights = fullyconnect(scores,weightsV,biasV,'DataFormat','CB');
            attentionWeights = reshape(attentionWeights,1,numFeatures,miniBatchSize);
            attentionWeights = softmax(attentionWeights,'DataFormat','SCB');
            
            % Context.
            contextVector = attentionWeights .* features;
            contextVector = squeeze(sum(contextVector,2));
            
        end

        %% Batch Creation Function
            function [dlX, dlT] = createBatch(X,documents,dlnet,inputMin,inputMax,enc,executionEnvironment)
                
                dlX = extractImageFeatures(dlnet,X,inputMin,inputMax,executionEnvironment);
                
                % Convert documents to sequences of word indices.
                T = doc2sequence(enc,documents,'PaddingDirection','right','PaddingValue',enc.NumWords+1);
                T = cat(1,T{:});
                
                % Convert mini-batch of data to dlarray.
                dlT = dlarray(T);
                
                % If training on a GPU, then convert data to gpuArray.
                if (executionEnvironment == "auto" && canUseGPU) || executionEnvironment == "gpu"
                    dlT = gpuArray(dlT);
                end
                
            end
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.jpg','*.png');
            app.textImage = imread([app.path,app.file]);
            imshow(app.textImage,'Parent',app.UIAxes2);
            app.Image_Captioning_UIFigure.Visible = 0;
            app.Image_Captioning_UIFigure.Visible = 1;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            fid = fopen('export.txt','wt');
            fprintf(fid,app.caption);
            fclose(fid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Image_Captioning_UIFigure = uifigure('Visible', 'off');
            app.Image_Captioning_UIFigure.Name = 'Image Captioning';
            app.InputImageButton = uibutton(app.Image_Captioning_UIFigure, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = {'輸入影像'; 'Input Image'};
            app.UIAxes2 = uiaxes(app.Image_Captioning_UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            app.ImageAttentionButton = uibutton(app.Image_Captioning_UIFigure, 'push');
            app.ImageAttentionButton.ButtonPushedFcn = createCallbackFcn(app, @ImageAttentionButtonPushed, true);
            app.ImageAttentionButton.Text = {'看圖說故事'; 'Image Attention'};
            app.ExportButton = uibutton(app.Image_Captioning_UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = {'輸出'; 'Export'};
            app.Label = uilabel(app.Image_Captioning_UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.Image_Captioning_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Captioning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Image_Captioning_UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Image_Captioning_UIFigure)
        end
    end
end